classdef gith < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        MARKTESTSTARTButton         matlab.ui.control.StateButton
        HOLDButton                  matlab.ui.control.Button
        SEQUENCESTARTButton         matlab.ui.control.Button
        MANUALVALVEPOSTButton       matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        WATERFlowButton             matlab.ui.control.StateButton
        IGNITEButton                matlab.ui.control.StateButton
        LOXPurgeButton              matlab.ui.control.StateButton
        FUELPurgeButton             matlab.ui.control.StateButton
        MAINButton                  matlab.ui.control.StateButton
        LOXVentButton               matlab.ui.control.StateButton
        FUELVentButton              matlab.ui.control.StateButton
        LOXPressButton              matlab.ui.control.StateButton
        FUELPressButton             matlab.ui.control.StateButton
        ManualValveControlLabel     matlab.ui.control.Label
        ABORTButton                 matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        SequenceInspectPanel        matlab.ui.container.Panel
        Tree                        matlab.ui.container.Tree
        LeftPanel                   matlab.ui.container.Panel
        LOXLVLCachingButton         matlab.ui.control.StateButton
        LOXLVLGauge                 matlab.ui.control.Gauge
        LOXLVLGaugeLabel            matlab.ui.control.Label
        PropLoadButton              matlab.ui.control.Button
        QueryStandButton            matlab.ui.control.StateButton
        Image                       matlab.ui.control.Image
        ImportSequenceButton        matlab.ui.control.Button
        SequenceEditField           matlab.ui.control.EditField
        SequenceEditFieldLabel      matlab.ui.control.Label
        InstantPostButton           matlab.ui.control.StateButton
        debugButton                 matlab.ui.control.Button
        RPIIPAddressEditField       matlab.ui.control.EditField
        RPIIPAddressEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        instantPost; % Toggles instant button posting to http
        desiredState; % weird fix?? POP
        sequenceString; % cringe
        refreshTimer;   % App refresh timer
    end
    
    methods (Access = public)
        function refreshTick(app,~,~)
            try
                % Grab updated valve data
                response = app.desiredState.poll();
                app.writeStateToGui(response);
                % Grab updated LOXLVL data
%                 response = app.desiredState.pollLOX();
%                 app.writeLOXLVLToGUI(response);
            catch ME
                % The error propagates upward so catch it here as well
                disp('STAND NOT ACTIVE')
            end
        end
        
        function colorizeTreeNode(app, nodeID, state)
%             disp(nodeID)
%             disp(state)
            switch state
                case 'expired'
                    nodeStyle = uistyle("BackgroundColor",'#e57777',"FontWeight","bold");
                case 'running'
                    nodeStyle = uistyle("BackgroundColor","#a0ff75","FontWeight","bold");
                case 'waiting'
                    nodeStyle = uistyle("BackgroundColor","#e8ce49","FontWeight","bold");
                case 'aborted'
                    nodeStyle = uistyle("BackgroundColor","#78756e","FontWeight","bold");
            end
%             disp('style set')
            % Pull all child nodes
            nodes = get(app.Tree.Children(nodeID));
            nodeStates = nodes.Children(2);
            % Apply styles to the nodes
            addStyle(app.Tree,nodeStyle,"node",app.Tree.Children(nodeID))
            addStyle(app.Tree,nodeStyle,"node",nodes.Children)
            addStyle(app.Tree,nodeStyle,"node",nodeStates.Children)
%             disp('style applied')
        end
        
        function writeStateToGui(app, states)
            % Accepts a json-like of state names and state valuves and
            % updates ui buttons accordingly
            
            % Pull a list of all fields
            stateNames = fieldnames(states);
            for n = 1:length(stateNames)
                switch stateNames{n}
                    case 'FUEL_Press'
                        app.FUELPressButton.Text=["FUEL-Press:", states.FUEL_Press];
                    case 'LOX_Press'
                        app.LOXPressButton.Text=["LOX-Press:", states.LOX_Press];
                    case 'FUEL_Vent'
                        app.FUELVentButton.Text=["FUEL-Vent:", states.FUEL_Vent];
                    case 'LOX_Vent'
                        app.LOXVentButton.Text=["LOX-Vent:", states.LOX_Vent];
                    case 'MAIN'
                        app.MAINButton.Text=["MAIN:", states.MAIN];
                    case 'FUEL_Purge'
                        app.FUELPurgeButton.Text=["FUEL-Purge:", states.FUEL_Purge];
                    case 'LOX_Purge'
                        app.LOXPurgeButton.Text=["LOX-Purge:", states.LOX_Purge];
                    case 'IGNITE'
                        app.IGNITEButton.Text=["IGNITE:", states.IGNITE];
                    case 'WATER_Flow'
                        app.WATERFlowButton.Text=["WATER-Flow:", states.WATER_Flow];
                end
            end
        end 
        
        function writeLOXLVLToGUI(app, values)
            % Accepts a json-like object with a 'LOXLVL' field and
            % percentage value and re-renders the meter in the GUI
%             disp(class(values))
            app.LOXLVLGauge.Value = str2double(values);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.desiredState = StateMachine(app);
            value = app.InstantPostButton.Value;
            app.instantPost = value;
            app.desiredState.ip = app.RPIIPAddressEditField.Value;
            
            % Destroy all timers from previous instances of the app
            % Locate all timers
            TimerList = timerfindall;
            % If there are timers
            if ~isempty(TimerList)
                % Check them all
                for n = 1:length(TimerList)
                    % If the timer name matches the static name
                    if convertCharsToStrings(TimerList(n).Name) == 'MATLAPP_RefreshTimer'
                        % Stop the timer
                        stop(TimerList(n));
                        % Then delete the timer
                        delete(TimerList(n));
                    end
                end
            end
            
            % Build a new timer
            app.refreshTimer = timer( ...
                'Name', 'MATLAPP_RefreshTimer', ...
                'Period', 0.25, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf, ...
                'TimerFcn', @app.refreshTick);
        end

        % Value changed function: RPIIPAddressEditField
        function RPIIPAddressEditFieldValueChanged(app, event)
            value = app.RPIIPAddressEditField.Value;
            app.desiredState.ip = value;
        end

        % Value changed function: FUELPressButton
        function FUELPressButtonValueChanged(app, event)
            value = app.FUELPressButton.Value;
            app.desiredState.FUEL_Press = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
        end

        % Value changed function: LOXPressButton
        function LOXPressButtonValueChanged(app, event)
            value = app.LOXPressButton.Value;
            app.desiredState.LOX_Press = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.LOXPressButton.Text=["LOX-Press:", response.LOX_Press];
%             end
        end

        % Callback function
        function InstantValvePostSwitchValueChanged(app, event)
            value = app.InstantValvePostSwitch.Value;
            app.instantPost = value;
        end

        % Button pushed function: MANUALVALVEPOSTButton
        function MANUALVALVEPOSTButtonPushed(app, event)
            response = app.desiredState.post();
            app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
            app.LOXPressButton.Text=["LOX-Press:", response.LOX_Press];
            app.FUELVentButton.Text=["FUEL-Vent:", response.FUEL_Vent];
            app.LOXVentButton.Text=["LOX-Vent:", response.LOX_Vent];
            app.MAINButton.Text=["MAIN:", response.MAIN];
            app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
            app.LOXPurgeButton.Text=["LOX-Purge:", response.LOX_Purge];
            app.IGNITEButton.Text=["IGNITE:", response.IGNITE];
        end

        % Value changed function: FUELVentButton
        function FUELVentButtonValueChanged(app, event)
            value = app.FUELVentButton.Value;
            app.desiredState.FUEL_Vent = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.FUELVentButton.Text=["FUEL-Vent:", response.FUEL_Vent];
%             end
        end

        % Value changed function: LOXVentButton
        function LOXVentButtonValueChanged(app, event)
            value = app.LOXVentButton.Value;
            app.desiredState.LOX_Vent = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.LOXVentButton.Text=["LOX-Vent:", response.LOX_Vent];
%             end
        end

        % Value changed function: MAINButton
        function MAINButtonValueChanged(app, event)
            value = app.MAINButton.Value;
            app.desiredState.MAIN = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.MAINButton.Text=["MAIN:", response.MAIN];
%             end
        end

        % Value changed function: FUELPurgeButton
        function FUELPurgeButtonValueChanged(app, event)
            value = app.FUELPurgeButton.Value;
            app.desiredState.FUEL_Purge = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
%             end
        end

        % Value changed function: LOXPurgeButton
        function LOXPurgeButtonValueChanged(app, event)
            value = app.LOXPurgeButton.Value;
            app.desiredState.LOX_Purge = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.LOXPurgeButton.Text=["LOX-Purge", response.LOX_Purge];
%             end
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
            print(app.desiredState)
        end

        % Callback function
        function InstaPostToggleSwitchValueChanged(app, event)
            value = app.InstaPostToggleSwitch.Value;
            app.instantPost = value;
        end

        % Value changed function: InstantPostButton
        function InstantPostButtonValueChanged(app, event)
            value = app.InstantPostButton.Value;
            app.instantPost = value;
            
        end

        % Button pushed function: ImportSequenceButton
        function ImportSequenceButtonPushed(app, event)
            % wipe tree nodes to write updated sequence (for multiple
            % imports)
            app.Tree.Children.delete();
            app.desiredState.parseSequence(app.SequenceEditField.Value);
            % disp(app.desiredState.sequence);

            % read json state "name" indices to reference individual states
            names = fieldnames(app.desiredState.sequence);
            seq = app.desiredState.sequence;
            firstLevel = {}; % weird fix
            
            % construct node tree to display sequence on GUI
            for i = 1:length(names)
                %disp(names{i})
                firstLevel{i} = uitreenode( app.Tree,'Text', getfield(seq, names{i}).Name );
%                 disp(firstLevel)
%                 disp(firstLevel{i})
                a = uitreenode( firstLevel{i},'Text', sprintf('Duration: %s s', num2str(getfield(seq, names{i}).Duration) ) );
                b = uitreenode( firstLevel{i},'Text','State');
                
                KeyList = {
                    'FUEL_Press',
                    'LOX_Press',
                    'FUEL_Vent',
                    'LOX_Vent',
                    'MAIN',
                    'FUEL_Purge',
                    'LOX_Purge',
                    'IGNITE',
                    'WATER_Flow'
                };
                
                % show valve states in tree
                state = getfield(seq, names{i}).State;
                
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{1}, ...
                    tern(num2str(getfield(state,KeyList{1})),'0','CLOSED','1','OPEN')));
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{2}, ...
                    tern(num2str(getfield(state,KeyList{2})),'0','CLOSED','1','OPEN')));
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{3}, ...
                    tern(num2str(getfield(state,KeyList{3})),'0','OPEN','1','CLOSED')));    % Vents are inverted
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{4}, ...
                    tern(num2str(getfield(state,KeyList{4})),'0','OPEN','1','CLOSED')));    % Vents are inverted
                c = uitreenode(b, 'Text', sprintf('%s: \t\t%s', KeyList{5}, ...
                    tern(num2str(getfield(state,KeyList{5})),'0','CLOSED','1','OPEN')));
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{6}, ...
                    tern(num2str(getfield(state,KeyList{6})),'0','CLOSED','1','OPEN')));
                c = uitreenode(b, 'Text', sprintf('%s: \t%s', KeyList{7}, ...
                    tern(num2str(getfield(state,KeyList{7})),'0','CLOSED','1','OPEN')));
                c = uitreenode(b, 'Text', sprintf('%s: \t\t%s', KeyList{8}, ...
                    tern(num2str(getfield(state,KeyList{8})),'0','OFF','1','FIRING')));
                c = uitreenode(b, 'Text', sprintf('%s: %s', KeyList{9}, ...
                    tern(num2str(getfield(state,KeyList{7})),'0','CLOSED','1','OPEN')));
%                 for j = 1:length(KeyList)
%                     c = uitreenode (b,'Text', sprintf('%s: %s', KeyList{j}, num2str(getfield(state,KeyList{j})) ));
%                 end
                
            end
%             a = uitreenode( firstLevel(i),'Text', getfield(seq, names{i}).Duration );
            
            expand(app.Tree)
%             disp(app.desiredState.timers(1).startDelay)
        end

        % Value changed function: SequenceEditField
        function SequenceEditFieldValueChanged(app, event)
            %value = str(app.SequenceEditField.Value); % wEiRd FiX
            %app.sequenceString = value;
        end

        % Button pushed function: SEQUENCESTARTButton
        function SEQUENCESTARTButtonPushed(app, event)
%             tic
            start(app.desiredState.timers(1))
        end

        % Button pushed function: HOLDButton
        function HOLDButtonPushed(app, event)
            app.desiredState.stopTimer();
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.desiredState.abortTimer();
            
            % reset timer array with parseSequence method
%             app.desiredState.parseSequence(app.SequenceEditField.Value);

            % hardcode safe state
%             app.desiredState.safe();
            try
                response = app.desiredState.safe();
                app.writeStateToGui(response);
            catch ME
                % The error propagates upward so catch it here as well
                disp('STAND NOT ACTIVE')
            end
        end

        % Size changed function: UIFigure
        function updateAppLayout(app, event)

        end

        % Value changed function: QueryStandButton
        function QueryStandButtonValueChanged(app, event)
            value = app.QueryStandButton.Value;
            % Start the query timer
            
            if value
                start(app.refreshTimer);
            else
                stop(app.refreshTimer);
            end
            
        end

        % Value changed function: MARKTESTSTARTButton
        function MARKTESTSTARTButtonValueChanged(app, event)
            value = app.MARKTESTSTARTButton.Value;
            
            if value
                % Access the test marker start url
                url = strcat('http://',app.desiredState.ip,':3002/event/test/START');
                response = webread(url);
                if response == 'Start marker inserted'
                    app.MARKTESTSTARTButton.Text = ['MARK TEST END'];
                    app.MARKTESTSTARTButton.BackgroundColor = [0.47,0.67,0.19];
                end
            else
                % Access the test marker end url
                url = strcat('http://',app.desiredState.ip,':3002/event/test/END');
                response = webread(url);
                if response == 'End marker inserted'
                    app.MARKTESTSTARTButton.Text = ['MARK TEST START'];
                    app.MARKTESTSTARTButton.BackgroundColor = [1.00,0.00,0.00];
                end
            end
        end

        % Button pushed function: PropLoadButton
        function PropLoadButtonPushed(app, event)
            % Prepare the lox loading state
            % Recall that these are the circuit level actions, not open/closed
            obj.FUEL_Press = false;
            obj.LOX_Press = false;
            obj.FUEL_Vent = false;
            obj.LOX_Vent = false;
            obj.MAIN = true;
            obj.FUEL_Purge = false;
            obj.LOX_Purge = false;
            obj.IGNITE = false;
            % Write it to the stand
            app.desiredState.post();
            % Poll stand for an update
            pause(0.25);
            app.refreshTick;
        end

        % Value changed function: IGNITEButton
        function IGNITEButtonValueChanged(app, event)
            value = app.IGNITEButton.Value;
            app.desiredState.IGNITE = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
%             end
        end

        % Value changed function: LOXLVLCachingButton
        function LOXLVLCachingButtonValueChanged(app, event)
            value = app.LOXLVLCachingButton.Value;
            
            if value
                % Start caching LOXLVL values
                url = strcat('http://',app.RPIIPAddressEditField.Value,':3004/serial/auxdata/START');
                options = weboptions;
                options.RequestMethod = 'get';
                options.Timeout = 1;
                try
                    webread(url,options);
    %                 disp(response)
                catch ME
    %                 disp(ME)
                    if (strcmp(ME.identifier,'MATLAB:webservices:Timeout')) 
                        disp('=== TIMEOUT ON LOXLVL CACHING START ===')
                    end
                end
            else
                % Stop caching LOXLVL values
                url = strcat('http://',app.RPIIPAddressEditField.Value,':3004/serial/auxdata/CLOSE');
                options = weboptions;
                options.RequestMethod = 'get';
                options.Timeout = 1;
                try
                    webread(url,options);
    %                 disp(response)
                catch ME
    %                 disp(ME)
                    if (strcmp(ME.identifier,'MATLAB:webservices:Timeout')) 
                        disp('=== TIMEOUT ON LOXLVL CACHING START ===')
                    end
                end
            end
        end

        % Value changed function: WATERFlowButton
        function WATERFlowButtonValueChanged(app, event)
            value = app.WATERFlowButton.Value;
            app.desiredState.WATER_Flow = value;
            
            if(app.instantPost)
                try
                    response = app.desiredState.post();
                    app.writeStateToGui(response)
                catch ME    
                    % The error propagates upward so catch it here as well
                    disp('STAND NOT ACTIVE')
                end
%                 response = app.desiredState.post();
%                 app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
%                 app.writeStateToGui(response)
            end
            
%             if(app.instantPost)
%                 response = app.desiredState.post();
%                 app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RPIIPAddressEditFieldLabel = uilabel(app.LeftPanel);
            app.RPIIPAddressEditFieldLabel.Text = 'RPI IP Address';
            app.RPIIPAddressEditField = uieditfield(app.LeftPanel, 'text');
            app.RPIIPAddressEditField.ValueChangedFcn = createCallbackFcn(app, @RPIIPAddressEditFieldValueChanged, true);
            app.RPIIPAddressEditField.Value = '192.168.137.10';
            app.debugButton = uibutton(app.LeftPanel, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Enable = 'off';
            app.debugButton.Visible = 'off';
            app.debugButton.Text = 'debug';
            app.InstantPostButton = uibutton(app.LeftPanel, 'state');
            app.InstantPostButton.ValueChangedFcn = createCallbackFcn(app, @InstantPostButtonValueChanged, true);
            app.InstantPostButton.Text = 'Instant Post';
            app.SequenceEditFieldLabel = uilabel(app.LeftPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.LeftPanel, 'text');
            app.SequenceEditField.ValueChangedFcn = createCallbackFcn(app, @SequenceEditFieldValueChanged, true);
            app.SequenceEditField.Value = 'sequence.json';
            app.ImportSequenceButton = uibutton(app.LeftPanel, 'push');
            app.ImportSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSequenceButtonPushed, true);
            app.ImportSequenceButton.Text = 'Import Sequence';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logo_transparent.svg';
            app.QueryStandButton = uibutton(app.LeftPanel, 'state');
            app.QueryStandButton.ValueChangedFcn = createCallbackFcn(app, @QueryStandButtonValueChanged, true);
            app.QueryStandButton.Text = 'Query Stand';
            app.PropLoadButton = uibutton(app.LeftPanel, 'push');
            app.PropLoadButton.ButtonPushedFcn = createCallbackFcn(app, @PropLoadButtonPushed, true);
            app.PropLoadButton.Text = 'Prop Load';
            app.LOXLVLGaugeLabel = uilabel(app.LeftPanel);
            app.LOXLVLGaugeLabel.Visible = 'off';
            app.LOXLVLGaugeLabel.Text = 'LOXLVL';
            app.LOXLVLGauge = uigauge(app.LeftPanel, 'circular');
            app.LOXLVLGauge.Limits = [0 105];
            app.LOXLVLGauge.Visible = 'off';
            app.LOXLVLCachingButton = uibutton(app.LeftPanel, 'state');
            app.LOXLVLCachingButton.ValueChangedFcn = createCallbackFcn(app, @LOXLVLCachingButtonValueChanged, true);
            app.LOXLVLCachingButton.Text = 'LOXLVL Caching';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SequenceInspectPanel = uipanel(app.CenterPanel);
            app.SequenceInspectPanel.Title = 'Sequence Inspect';
            app.Tree = uitree(app.SequenceInspectPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ABORTButton = uibutton(app.GridLayout2, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ManualValveControlLabel = uilabel(app.GridLayout3);
            app.ManualValveControlLabel.Text = 'Manual Valve Control';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.FUELPressButton = uibutton(app.GridLayout4, 'state');
            app.FUELPressButton.ValueChangedFcn = createCallbackFcn(app, @FUELPressButtonValueChanged, true);
            app.FUELPressButton.Text = 'FUEL-Press';
            app.LOXPressButton = uibutton(app.GridLayout4, 'state');
            app.LOXPressButton.ValueChangedFcn = createCallbackFcn(app, @LOXPressButtonValueChanged, true);
            app.LOXPressButton.Text = 'LOX-Press';
            app.FUELVentButton = uibutton(app.GridLayout4, 'state');
            app.FUELVentButton.ValueChangedFcn = createCallbackFcn(app, @FUELVentButtonValueChanged, true);
            app.FUELVentButton.Text = 'FUEL-Vent';
            app.FUELVentButton.Value = true;
            app.LOXVentButton = uibutton(app.GridLayout4, 'state');
            app.LOXVentButton.ValueChangedFcn = createCallbackFcn(app, @LOXVentButtonValueChanged, true);
            app.LOXVentButton.Text = 'LOX-Vent';
            app.LOXVentButton.Value = true;
            app.MAINButton = uibutton(app.GridLayout4, 'state');
            app.MAINButton.ValueChangedFcn = createCallbackFcn(app, @MAINButtonValueChanged, true);
            app.MAINButton.Text = 'MAIN';
            app.FUELPurgeButton = uibutton(app.GridLayout4, 'state');
            app.FUELPurgeButton.ValueChangedFcn = createCallbackFcn(app, @FUELPurgeButtonValueChanged, true);
            app.FUELPurgeButton.Text = 'FUEL-Purge';
            app.LOXPurgeButton = uibutton(app.GridLayout4, 'state');
            app.LOXPurgeButton.ValueChangedFcn = createCallbackFcn(app, @LOXPurgeButtonValueChanged, true);
            app.LOXPurgeButton.Text = 'LOX-Purge';
            app.IGNITEButton = uibutton(app.GridLayout4, 'state');
            app.IGNITEButton.ValueChangedFcn = createCallbackFcn(app, @IGNITEButtonValueChanged, true);
            app.IGNITEButton.Text = 'IGNITE';
            app.WATERFlowButton = uibutton(app.GridLayout4, 'state');
            app.WATERFlowButton.ValueChangedFcn = createCallbackFcn(app, @WATERFlowButtonValueChanged, true);
            app.WATERFlowButton.Text = 'WATER-Flow';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.MANUALVALVEPOSTButton = uibutton(app.GridLayout5, 'push');
            app.MANUALVALVEPOSTButton.ButtonPushedFcn = createCallbackFcn(app, @MANUALVALVEPOSTButtonPushed, true);
            app.MANUALVALVEPOSTButton.Text = 'MANUAL VALVE POST';
            app.SEQUENCESTARTButton = uibutton(app.GridLayout5, 'push');
            app.SEQUENCESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @SEQUENCESTARTButtonPushed, true);
            app.SEQUENCESTARTButton.Text = 'SEQUENCE START';
            app.HOLDButton = uibutton(app.GridLayout5, 'push');
            app.HOLDButton.ButtonPushedFcn = createCallbackFcn(app, @HOLDButtonPushed, true);
            app.HOLDButton.Text = 'HOLD';
            app.MARKTESTSTARTButton = uibutton(app.GridLayout5, 'state');
            app.MARKTESTSTARTButton.ValueChangedFcn = createCallbackFcn(app, @MARKTESTSTARTButtonValueChanged, true);
            app.MARKTESTSTARTButton.Text = {'MARK TEST START'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gith

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end